classdef Exercicio1App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        SairButton               matlab.ui.control.Button
        EscolhadevaloresMnimoMximoLabel  matlab.ui.control.Label
        GrficosrepresentadosToolboxValorLabel  matlab.ui.control.Label
        EX1Exerccio1dafichaqueencontraromnimomximodafunosenousandoalgoritmosgenticosGAProgramadeoptimizacaoXGAFITNESSFCNNVARSAbAeqbeqlbubNONLCONoptionsLabel  matlab.ui.control.Label
        ValorDropDown            matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        MENUPRINCIPALButton      matlab.ui.control.Button
        GrficoButton             matlab.ui.control.Button
        ToolboxButton            matlab.ui.control.Button
        OPTIMIZAOSENOCOMGALabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ToolboxButton
        function ToolboxButtonPushed(app, event)
            tipo = app.ValorDropDown.Value; % "Mínimo" ou "Máximo"
    
            if strcmp(tipo, 'Mínimo')
                gasenoMin_toolbox;
                valorReal = fval
            else
                gasenoMax_toolbox;
                valorReal = -fval
            end
            % Mostra uma mensagem legível para o utilizador
            msg = sprintf(['✅ Resultados da Toolbox\n\n' ...
                        'x ótimo = %.4f rad\n' ...
                        'sin(x) = %.4f'], x, valorReal);
            uialert(app.UIFigure, msg, 'Resultado da Otimização');
        
        end

        % Button pushed function: MENUPRINCIPALButton
        function MENUPRINCIPALButtonPushed(app, event)
            MenuAPP;  % volta ao menu principal
            delete(app);
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: GrficoButton
        function GrficoButtonPushed(app, event)
            tipo = app.ValorDropDown.Value;
    
            if strcmp(tipo, 'Mínimo')
                gasenoMin_grafico;
            else
                gasenoMax_grafico;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OPTIMIZAOSENOCOMGALabel = uilabel(app.GridLayout);
            app.OPTIMIZAOSENOCOMGALabel.Text = 'OPTIMIZAÇÃO SENO COM GA';
            app.ToolboxButton = uibutton(app.GridLayout, 'push');
            app.ToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @ToolboxButtonPushed, true);
            app.ToolboxButton.Text = 'Toolbox';
            app.GrficoButton = uibutton(app.GridLayout, 'push');
            app.GrficoButton.ButtonPushedFcn = createCallbackFcn(app, @GrficoButtonPushed, true);
            app.GrficoButton.Text = 'Gráfico';
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MENUPRINCIPALButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.ValorDropDown = uidropdown(app.GridLayout);
            app.ValorDropDown.Items = {'Mínimo', 'Máximo'};
            app.ValorDropDown.Value = 'Mínimo';
            app.EX1Exerccio1dafichaqueencontraromnimomximodafunosenousandoalgoritmosgenticosGAProgramadeoptimizacaoXGAFITNESSFCNNVARSAbAeqbeqlbubNONLCONoptionsLabel = uilabel(app.GridLayout);
            app.EX1Exerccio1dafichaqueencontraromnimomximodafunosenousandoalgoritmosgenticosGAProgramadeoptimizacaoXGAFITNESSFCNNVARSAbAeqbeqlbubNONLCONoptionsLabel.Text = {'EX1:'; 'Exercício 1 da ficha, que é encontrar o mínimo/máximo da função seno usando algoritmos genéticos (GA)'; ''; '%Programa de optimizacao'; '%X = GA(FITNESSFCN,NVARS,A,b,Aeq,beq,lb,ub,NONLCON,options)'};
            app.GrficosrepresentadosToolboxValorLabel = uilabel(app.GridLayout);
            app.GrficosrepresentadosToolboxValorLabel.Text = {'Gráficos representados!'; 'Toolbox;'; 'Valor;'};
            app.EscolhadevaloresMnimoMximoLabel = uilabel(app.GridLayout);
            app.EscolhadevaloresMnimoMximoLabel.Text = {'Escolha de valores:'; 'Mínimo:'; 'Máximo:'};
            app.SairButton = uibutton(app.GridLayout, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exercicio1App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end