classdef MenuAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        TextArea_Titulo         matlab.ui.control.TextArea
        TextArea_Ex1            matlab.ui.control.TextArea
        TextArea_Ex2            matlab.ui.control.TextArea
        Exerccio2Button         matlab.ui.control.Button
        MENUPRINCIPALButton     matlab.ui.control.Button
        SairButton              matlab.ui.control.Button
        Exerccio1Button         matlab.ui.control.Button
        Ex1TextAreaSetor1       matlab.ui.control.TextArea
        Ex2TextAreaSetor2       matlab.ui.control.TextArea
        TextAreaSetorPrincipal  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MenuApp(app)
            
        end

        % Button pushed function: Exerccio1Button
        function Exerccio1ButtonPushed(app, event)
            Exercicio1App;  % abre a app do exercício 1
            delete(app);    % fecha o menu principal
        end

        % Button pushed function: Exerccio2Button
        function Exerccio2ButtonPushed(app, event)
            Exercicio2App;%abre a app do exercício 2
            delete(app);% defcha o menu principal
        end

        % Callback function: MENUPRINCIPALButton, UIFigure
        function MenuButtonPushed(app, event)
            MenuAPP;%retorna sempre ao menu principal 
        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app);    % fecha o menu principal
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TextAreaSetorPrincipal = uitextarea(app.GridLayout);
            app.Ex2TextAreaSetor2 = uitextarea(app.GridLayout);
            app.Ex2TextAreaSetor2.Visible = 'off';
            app.Ex1TextAreaSetor1 = uitextarea(app.GridLayout);
            app.Ex1TextAreaSetor1.Visible = 'off';
            app.Exerccio1Button = uibutton(app.GridLayout, 'push');
            app.Exerccio1Button.ButtonPushedFcn = createCallbackFcn(app, @Exerccio1ButtonPushed, true);
            app.Exerccio1Button.Text = 'Exercício 1';
            app.SairButton = uibutton(app.GridLayout, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.Exerccio2Button = uibutton(app.GridLayout, 'push');
            app.Exerccio2Button.ButtonPushedFcn = createCallbackFcn(app, @Exerccio2ButtonPushed, true);
            app.Exerccio2Button.Text = 'Exercício 2';
            app.TextArea_Ex2 = uitextarea(app.GridLayout);
            app.TextArea_Ex2.Editable = 'off';
            app.TextArea_Ex2.Value = {'EX2:'; ''; 'Despacho económico sem perdas da rede multiplicadores de Lagrange. Recorrendo à Toolbox de Otimização do MATLAB'};
            app.TextArea_Ex1 = uitextarea(app.GridLayout);
            app.TextArea_Ex1.Editable = 'off';
            app.TextArea_Ex1.Value = {'EX1:'; ''; 'Determinação do mínimo e do máximo da função seno'; 'Recorrendo à Toolbox de Otimização do MATLAB'};
            app.TextArea_Titulo = uitextarea(app.GridLayout);
            app.TextArea_Titulo.Value = {'MATLAB Aplicação-Resolução de Exercícios '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MenuAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @MenuApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end