classdef Exercicio2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        PmProbabilidadedeMutaoLabel  matlab.ui.control.Label
        PcProbabilidadedeCrossoverLabel  matlab.ui.control.Label
        NgerNmerodeGeraesLabel       matlab.ui.control.Label
        NpopNmerodePopulaoLabel      matlab.ui.control.Label
        PMaxPotenciaMaximaLabel      matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        home1                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        TextArea                     matlab.ui.control.TextArea
        ComparaoButton               matlab.ui.control.Button
        AlgoritmoGeneticoButton      matlab.ui.control.Button
        TextArea_ex2                 matlab.ui.control.TextArea
        TextArea_Titulo              matlab.ui.control.TextArea
        PmEditField                  matlab.ui.control.NumericEditField
        PmEditFieldLabel             matlab.ui.control.Label
        PcEditField                  matlab.ui.control.NumericEditField
        PcEditFieldLabel             matlab.ui.control.Label
        NgerEditField                matlab.ui.control.NumericEditField
        NgerEditFieldLabel           matlab.ui.control.Label
        NpopEditField                matlab.ui.control.NumericEditField
        NpopEditFieldLabel           matlab.ui.control.Label
        PMaxEditField                matlab.ui.control.NumericEditField
        PMaxEditFieldLabel           matlab.ui.control.Label
        SairButton                   matlab.ui.control.Button
        ToolboxButton                matlab.ui.control.Button
        ValorDropDown                matlab.ui.control.DropDown
        MENUPRINCIPALButton          matlab.ui.control.Button
        TextArea_setor2              matlab.ui.control.TextArea
        TextArea_setor1              matlab.ui.control.TextArea
        TextArea_setorprincipal      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
            % ===== Resultados do GA feito por vocês =====
        PG1_opt
        PG2_opt
        custo_min
        
        % ===== Resultados do GA da Toolbox =====
        PG1_tool
        PG2_tool
        custo_min_tool
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: MENUPRINCIPALButton, home1
        function MENUPRINCIPALButtonPushed(app, event)
            MenuAPP; %retorna ao Menu Principal
            delete(app);% sai da aplicação
        end

        % Button pushed function: ToolboxButton
        function ToolboxButtonPushed(app, event)
            % Ler valores da App
            Pmin = 0;   
            PD = 0.9;   
            Pmax = app.PMaxEditField.Value;
            
            % Validar input
            if Pmax < Pmin
                uialert(app.UIFigure, 'Pmax deve ser maior que Pmin!', 'Erro');
                return;
            end

            if Pmax > PD
                uialert(app.UIFigure, 'Pmax não pode ser maior que PD!', 'Erro')
                return;
            end
            
            % Executar GA Toolbox
            set(0,'DefaultFigureVisible','on');
            [PG1_tool, PG2_tool, custo_min_tool] = ex2c_toolbox(Pmax);
            
            % Guardar resultados na App
            app.PG1_tool = PG1_tool;
            app.PG2_tool = PG2_tool;
            app.custo_min_tool = custo_min_tool;


            % Mostrar resultados em alerta
            msg = sprintf(['===== GA Toolbox =====\n' ...
                           'PG1 (tool) = %.4f p.u.\n' ...
                           'PG2 (tool) = %.4f p.u.\n' ...
                           'Custo mínimo = %.6f (AC/h)\n' ...
                           '=============================='], ...
                           PG1_tool, PG2_tool, custo_min_tool);
            
            uialert(app.UIFigure, msg, 'Resultados Toolbox');

        end

        % Button pushed function: SairButton
        function SairButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: AlgoritmoGeneticoButton
        function AlgoritmoGeneticoButtonPushed(app, event)
            % Ler valores da App
            Pmin = 0;   
            PD = 0.9;   
            Pmax = app.PMaxEditField.Value;
            Npop = app.NpopEditField.Value;
            Nger = app.NgerEditField.Value;
            Pc   = app.PcEditField.Value;
            Pm   = app.PmEditField.Value;
            
            % Validar inputs
            if Pmax < Pmin
                uialert(app.UIFigure, 'Pmax deve ser maior que Pmin!', 'Erro');
                return;
            end

            if Pmax > PD
                uialert(app.UIFigure, 'Pmax não pode ser maior que PD!', 'Erro')
                return;
            end

            if Npop <= 0 || mod(Npop,1)~=0
                uialert(app.UIFigure, 'O tamanho da população deve ser um inteiro > 0.', 'Erro');
                return;
            end
            if Nger <= 0 || mod(Nger,1)~=0
                uialert(app.UIFigure, 'O número de gerações deve ser um inteiro > 0.', 'Erro');
                return;
            end
            if Pc < 0 || Pc > 1
                uialert(app.UIFigure, 'Pc deve estar entre 0 e 1.', 'Erro');
                return;
            end
            if Pm < 0 || Pm > 1
                uialert(app.UIFigure, 'Pm deve estar entre 0 e 1.', 'Erro');
                return;
            end
            
            % Rodar GA conforme seleção
            tipo = app.ValorDropDown.Value;  % "Selec.Roleta" ou "Selec.Torneio"
            set(0,'DefaultFigureVisible','on');  % Mostrar gráficos
            
            if strcmp(tipo, 'Seleção --> Roleta')
                [PG1_opt, PG2_opt, custo_min] = ex2c_roleta_ga(Pmax, Npop, Nger, Pc, Pm);
            elseif strcmp(tipo, 'Seleção --> Torneio')
                [PG1_opt, PG2_opt, custo_min] = ex2c_torneio_ga(Pmax, Npop, Nger, Pc, Pm);
            else
                uialert(app.UIFigure, 'Seleção inválida!', 'Erro');
                return;
            end
            
            % Guardar resultados na App
            app.PG1_opt = PG1_opt;
            app.PG2_opt = PG2_opt;
            app.custo_min = custo_min;


            % Mostrar resultados em alerta
            msg = sprintf(['===== GA (Algoritmo Genético) =====\n' ...
                           'PG1 ótimo = %.4f p.u.\n' ...
                           'PG2 ótimo = %.4f p.u.\n' ...
                           'Custo mínimo = %.6f (AC/h)\n' ...
                           '=============================='], ...
                           PG1_opt, PG2_opt, custo_min);
            
            uialert(app.UIFigure, msg, 'Resultados GA');

        end

        % Callback function: ComparaoButton, Image2
        function ComparaoButtonPushed(app, event)
            % ============================
            %  VERIFICAR SE RESULTADOS EXISTEM
            % ============================
            if ~isprop(app,'PG1_opt') || isempty(app.PG1_opt) || ...
               ~isprop(app,'PG1_tool') || isempty(app.PG1_tool)
                uialert(app.UIFigure, 'Por favor, execute primeiro os GAs Roleta e Toolbox.', 'Erro');
                return;
            end
        
            % ============================
            %  MONTAR MENSAGEM DE COMPARAÇÃO
            % ============================
            tipo = app.ValorDropDown.Value;  % "Selec.Roleta" ou "Selec.Torneio"
            if strcmp(tipo, 'Seleção --> Roleta')
                msg = sprintf(['===== Comparação GA =====\n\n' ...
                               'GA Roleta:    PG1 = %.4f | PG2 = %.4f | Custo = %.6f\n' ...
                               'GA Toolbox:  PG1 = %.4f | PG2 = %.4f | Custo = %.6f\n'], ...
                               app.PG1_opt, app.PG2_opt, app.custo_min, ...
                               app.PG1_tool, app.PG2_tool, app.custo_min_tool);
            elseif strcmp(tipo, 'Seleção --> Torneio')
                 msg = sprintf(['===== Comparação GA =====\n\n' ...
                               'GA Torneio:    PG1 = %.4f | PG2 = %.4f | Custo = %.6f\n' ...
                               'GA Toolbox:  PG1 = %.4f | PG2 = %.4f | Custo = %.6f\n'], ...
                               app.PG1_opt, app.PG2_opt, app.custo_min, ...
                               app.PG1_tool, app.PG2_tool, app.custo_min_tool);             
            end
            % ============================
            %  EXIBIR ALERTA
            % ============================
            uialert(app.UIFigure, msg, 'Comparação GA');
        end

        % Value changed function: PcEditField
        function PcEditFieldValueChanged(app, event)
            value = app.PcEditField.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            app.PG1_tool = [];
            app.PG2_tool = [];
            app.custo_min_tool = [];

        end

        % Value changed function: PMaxEditField
        function PMaxEditFieldValueChanged(app, event)
            value = app.PMaxEditField.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            app.PG1_tool = [];
            app.PG2_tool = [];
            app.custo_min_tool = [];
        

        end

        % Value changed function: NpopEditField
        function NpopEditFieldValueChanged(app, event)
            value = app.NpopEditField.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            app.PG1_tool = [];
            app.PG2_tool = [];
            app.custo_min_tool = [];
        

        end

        % Value changed function: NgerEditField
        function NgerEditFieldValueChanged(app, event)
            value = app.NgerEditField.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            app.PG1_tool = [];
            app.PG2_tool = [];
            app.custo_min_tool = [];
        

        end

        % Value changed function: PmEditField
        function PmEditFieldValueChanged(app, event)
            value = app.PmEditField.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            app.PG1_tool = [];
            app.PG2_tool = [];
            app.custo_min_tool = [];
        

        end

        % Value changed function: ValorDropDown
        function ValorDropDownValueChanged(app, event)
            value = app.ValorDropDown.Value;
            % Resetar resultados antigos
            app.PG1_opt = [];
            app.PG2_opt = [];
            app.custo_min = [];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TextArea_setorprincipal = uitextarea(app.GridLayout);
            app.TextArea_setor1 = uitextarea(app.GridLayout);
            app.TextArea_setor1.Visible = 'off';
            app.TextArea_setor2 = uitextarea(app.GridLayout);
            app.TextArea_setor2.Visible = 'off';
            app.MENUPRINCIPALButton = uibutton(app.GridLayout, 'push');
            app.MENUPRINCIPALButton.ButtonPushedFcn = createCallbackFcn(app, @MENUPRINCIPALButtonPushed, true);
            app.MENUPRINCIPALButton.Text = 'MENU PRINCIPAL';
            app.ValorDropDown = uidropdown(app.GridLayout);
            app.ValorDropDown.Items = {'Seleção --> Roleta', 'Seleção --> Torneio'};
            app.ValorDropDown.ValueChangedFcn = createCallbackFcn(app, @ValorDropDownValueChanged, true);
            app.ValorDropDown.Value = 'Seleção --> Roleta';
            app.ToolboxButton = uibutton(app.GridLayout, 'push');
            app.ToolboxButton.ButtonPushedFcn = createCallbackFcn(app, @ToolboxButtonPushed, true);
            app.ToolboxButton.Text = 'Toolbox';
            app.SairButton = uibutton(app.GridLayout, 'push');
            app.SairButton.ButtonPushedFcn = createCallbackFcn(app, @SairButtonPushed, true);
            app.SairButton.Text = 'Sair';
            app.PMaxEditFieldLabel = uilabel(app.GridLayout);
            app.PMaxEditFieldLabel.Text = 'PMax';
            app.PMaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.PMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PMaxEditFieldValueChanged, true);
            app.PMaxEditField.Value = 0.9;
            app.NpopEditFieldLabel = uilabel(app.GridLayout);
            app.NpopEditFieldLabel.Text = 'Npop';
            app.NpopEditField = uieditfield(app.GridLayout, 'numeric');
            app.NpopEditField.ValueChangedFcn = createCallbackFcn(app, @NpopEditFieldValueChanged, true);
            app.NpopEditField.Value = 50;
            app.NgerEditFieldLabel = uilabel(app.GridLayout);
            app.NgerEditFieldLabel.Text = 'Nger';
            app.NgerEditField = uieditfield(app.GridLayout, 'numeric');
            app.NgerEditField.ValueChangedFcn = createCallbackFcn(app, @NgerEditFieldValueChanged, true);
            app.NgerEditField.Value = 100;
            app.PcEditFieldLabel = uilabel(app.GridLayout);
            app.PcEditFieldLabel.Text = 'Pc';
            app.PcEditField = uieditfield(app.GridLayout, 'numeric');
            app.PcEditField.ValueChangedFcn = createCallbackFcn(app, @PcEditFieldValueChanged, true);
            app.PcEditField.Value = 0.8;
            app.PmEditFieldLabel = uilabel(app.GridLayout);
            app.PmEditFieldLabel.Text = 'Pm';
            app.PmEditField = uieditfield(app.GridLayout, 'numeric');
            app.PmEditField.ValueChangedFcn = createCallbackFcn(app, @PmEditFieldValueChanged, true);
            app.PmEditField.Value = 0.1;
            app.TextArea_Titulo = uitextarea(app.GridLayout);
            app.TextArea_Titulo.Value = {'Optimização-Despacho da Rede GA'};
            app.TextArea_ex2 = uitextarea(app.GridLayout);
            app.TextArea_ex2.Value = {'EX2: Admitindo que a função custo de produção de cada um dos geradores'; 'são dadas pelas Equações (1) e (2):'; ''; 'C1(PG1) = 0,3· PG1 + 0,01· P^2G1;'; 'C2(PG2) = 0,2· PG2 + 0,3· P^2G2;'; ''; 'Recorrendo à Toolbox de Otimização do MATLAB, será realizado, o despacho económico sem perdas da rede e comparação com os resultados obtidos.'; ''; ''};
            app.AlgoritmoGeneticoButton = uibutton(app.GridLayout, 'push');
            app.AlgoritmoGeneticoButton.ButtonPushedFcn = createCallbackFcn(app, @AlgoritmoGeneticoButtonPushed, true);
            app.AlgoritmoGeneticoButton.Text = 'Algoritmo Genético';
            app.ComparaoButton = uibutton(app.GridLayout, 'push');
            app.ComparaoButton.ButtonPushedFcn = createCallbackFcn(app, @ComparaoButtonPushed, true);
            app.ComparaoButton.Text = 'Comparação ';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Escolha da potência máxima do Gerador 1:'};
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ComparaoButtonPushed, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'IconsDesign', 'compare.png');
            app.home1 = uiimage(app.GridLayout);
            app.home1.ImageClickedFcn = createCallbackFcn(app, @MENUPRINCIPALButtonPushed, true);
            app.home1.ImageSource = fullfile(pathToMLAPP, 'IconsDesign', 'home_24.png');
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.Value = {'Defina parâmetros do algoritmo genético!'};
            app.PMaxPotenciaMaximaLabel = uilabel(app.GridLayout);
            app.PMaxPotenciaMaximaLabel.Text = '  PMax - Potencia Maxima';
            app.NpopNmerodePopulaoLabel = uilabel(app.GridLayout);
            app.NpopNmerodePopulaoLabel.Text = '  Npop - Número de População';
            app.NgerNmerodeGeraesLabel = uilabel(app.GridLayout);
            app.NgerNmerodeGeraesLabel.Text = '  Nger - Número de Gerações';
            app.PcProbabilidadedeCrossoverLabel = uilabel(app.GridLayout);
            app.PcProbabilidadedeCrossoverLabel.Text = '  Pc - Probabilidade de Crossover';
            app.PmProbabilidadedeMutaoLabel = uilabel(app.GridLayout);
            app.PmProbabilidadedeMutaoLabel.Text = '  Pm - Probabilidade de Mutação';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exercicio2App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end